classdef HarvestMate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ESTOPLabel               matlab.ui.control.Label
        ManualModeLamp           matlab.ui.control.Lamp
        ManualModeLampLabel      matlab.ui.control.Label
        Joint7Slider             matlab.ui.control.Slider
        Joint7SliderLabel        matlab.ui.control.Label
        Joint6Slider             matlab.ui.control.Slider
        Joint6SliderLabel        matlab.ui.control.Label
        Joint5Slider             matlab.ui.control.Slider
        Joint5SliderLabel        matlab.ui.control.Label
        Joint4Slider             matlab.ui.control.Slider
        Joint4SliderLabel        matlab.ui.control.Label
        Joint3Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        Joint1Slider             matlab.ui.control.Slider
        Joint1SliderLabel        matlab.ui.control.Label
        RobotSwitch              matlab.ui.control.RockerSwitch
        ManualModeControlsLabel  matlab.ui.control.Label
        ManualSwitch             matlab.ui.control.Switch
        Lamp                     matlab.ui.control.Lamp
        GripperSlider            matlab.ui.control.Slider
        GripperSliderLabel       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        STARTButton_2            matlab.ui.control.Button
        Button_3                 matlab.ui.control.StateButton
        RobotActionButtonGroup   matlab.ui.container.ButtonGroup
        SortOnlyButton           matlab.ui.control.ToggleButton
        Button                   matlab.ui.control.ToggleButton
        DualActionButton         matlab.ui.control.ToggleButton
        HarvestOnlyButton        matlab.ui.control.ToggleButton
        ProduceButtonGroup       matlab.ui.container.ButtonGroup
        Button_2                 matlab.ui.control.ToggleButton
        MixedButton              matlab.ui.control.ToggleButton
        PotatoesButton           matlab.ui.control.ToggleButton
        TomatoesButton           matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)

        % Robot Related
        harvesterBot;
        sortingBot;
        rightGripper;
        leftGripper;
        rightHarvester;
        leftHarvester;
        rightSorter;
        leftSorter;
        steps;
        gripperLength;
        gripperHeight;
        pointDown;
        pointForwards;
        pointBackwards;
        selectedRobot;

        % Produce Related
        tomatoObject;
        tomatoVertices;
        potatoObject;
        potatoVertices;
        producePositions; % Array of positions for each produce item
        produceTags;      % Tags for identifying produce types
        % tomatoIndex;
        % potatoIndex;
    
        % Box locations for sorting
        unsortedBoxMix;
        unsortedBox;
        goodBox;
        badBox;
        unsortedBoxEEPos;
        goodBoxEEPose;
        badBoxEEPose;

    end
    
    methods (Access = private)

        function gripperAnimation(app,angle)
            % Produce link vector.
                leftQ = [deg2rad(-angle),deg2rad(angle),0];
                rightQ = [deg2rad(angle),deg2rad(-angle),0];
            % Animate gripper depending on robot.
            if strcmp(app.RobotSwitch.Value, 'Sorter')
                % animation for LinearUR3 gripper.
                app.leftSorter.model.animate(leftQ);
                app.rightSorter.model.animate(rightQ);

            elseif strcmp(app.RobotSwitch.Value, 'Harvester')
                % animation for Panda gripper.
                app.leftHarvester.model.animate(leftQ);
                app.rightHarvester.model.animate(rightQ);
            end
        end
        
        function UpdateJointPosition(app, jointIndex, sliderValue)
            % Converts slider value to radians and updates the selected robot's joint
            % configuration for the specified joint index.
            
            if isempty(app.selectedRobot)
                disp('No robot selected for manual control.');
                return;
            end

            % Convert the slider value (assumed to be in degrees) to radians
            jointAngle = rad2deg(sliderValue);
            
            % Get the current joint configuration and update the specified joint
            qCurrent = app.selectedRobot.model.getpos();
            qCurrent(jointIndex) = jointAngle;
            
            % Update the robot's joint configuration
            app.selectedRobot.model.animate(qCurrent);

            if strcmp(app.RobotSwitch.Value, 'Sorter')
                % Gripper base transform for LinearUR3.
                pos1 = app.selectedRobot.model.fkineUTS(app.selectedRobot.model.getpos())*transl(0,-0.0127,0.0612)*troty(-pi/2);%z0.0612
                pos2 = app.selectedRobot.model.fkineUTS(app.selectedRobot.model.getpos())*transl(0,0.0127,0.0612)*troty(-pi/2);%z0.0612

                app.leftSorter.model.base = pos2; 
                app.rightSorter.model.base = pos1; 

                app.leftSorter.model.animate(app.leftSorter.model.getpos());
                app.rightSorter.model.animate(app.rightSorter.model.getpos());

            elseif strcmp(app.RobotSwitch.Value, 'Harvester')
                % Gripper base transform for Panda.
                pos1 = app.selectedRobot.model.fkineUTS(app.selectedRobot.model.getpos())*transl(0,-0.0127,0.05)*troty(-pi/2);%z0.0612
                pos2 = app.selectedRobot.model.fkineUTS(app.selectedRobot.model.getpos())*transl(0,0.0127,0.05)*troty(-pi/2);%z0.0612

                app.leftHarvester.model.base = pos2; 
                app.rightHarvester.model.base = pos1; 

                app.leftHarvester.model.animate(app.leftHarvester.model.getpos());
                app.rightHarvester.model.animate(app.rightHarvester.model.getpos());
            end
        
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            % clear;
            app.Lamp.Color = 'green'; % Turn the lamp green to indicate "started"
            clf;
            pause(0.1);
            clc;
            set(0,'DefaultFigureWindowStyle','docked');
            view(3)
            hold on
            axis([-2.5, 2.5, -2.5, 2.5, 0.01, 2]);            
            disp('Initializing Environment and Robots...');
            
            % Load the environment
            LoadEnvironment();

            % locations for fruit to be placed when dropped
            [app.unsortedBoxMix,app.unsortedBox,app.goodBox,app.badBox] = ProduceClass.BoxLocations();

            app.steps = 50;
            app.gripperLength = 0.24;
            app.gripperHeight = 0.025;

            % Orientation for the end-effector.
            app.pointForwards = trotx(270, 'deg');
            app.pointDown = trotx(180,'deg');
            app.pointBackwards = trotx(90, 'deg');

            % unsorted box position for EE
            app.unsortedBoxEEPos = [0, 0, 0.6]; % box to drop produce
            
            % Sorted box positions for EE
            app.goodBoxEEPose = [0.6, -1.75, 0.6];
            app.badBoxEEPose = [1.1, -1, 0.6];


            [app.harvesterBot, app.sortingBot, app.rightHarvester, app.leftHarvester, app.rightSorter, app.leftSorter] = LoadRobots();

            disp('Environment and Robots Loaded');
            
        end

        % Selection changed function: ProduceButtonGroup
        function ProduceButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProduceButtonGroup.SelectedObject.Text;

            switch selectedButton
                case 'Tomatoes'
                    [app.producePositions, app.tomatoObject, app.tomatoVertices, app.produceTags] = ProduceClass.GenerateTomatoes();
                    disp('Generated Tomatoes.');
                case 'Potatoes'
                    [app.producePositions, app.tomatoObject, app.potatoVertices, app.produceTags] = ProduceClass.GeneratePotatoes();
                    disp('Generated Potatoes.');
                case 'Mixed'
                    [app.producePositions, app.potatoObject, app.potatoVertices, app.tomatoObject, app.tomatoVertices, app.produceTags] = ProduceClass.GenerateMix();
                    disp('Generated Mixed Produce.');
            end   
        end

        % Callback function: ProduceButtonGroup, RobotActionButtonGroup
        function ProduceButtonGroupButtonDown(app, event)
            selectedAction = app.RobotActionButtonGroup.SelectedObject.Text;
            switch selectedAction
                case 'Harvest Only'
                    disp('Harvester Started.');
                    StartHarvesting(app);
                case 'Sort Only'
                    disp('Sorter Started.');
                    StartSorting(app);
                case 'Dual Action'
                    disp('Dual Action Started.');
                    StartHarvesting(app);
                    StartSorting(app);
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            RobotClass.ToggleEStop();
            if value
                app.Lamp.Color = 'red';
            else
                app.Lamp.Color = 'green';
            end
        end

        % Value changed function: ManualSwitch
        function ManualSwitchValueChanged(app, event)
            % Ensure selectedRobot is initialized and contains the model property
            value = app.ManualSwitch.Value;
            if value
                app.ManualModeLamp.Color = 'blue';
            end

            if isempty(app.selectedRobot) || ~isprop(app.selectedRobot, 'model')
                disp('No robot selected or robot model not initialized.');
                return; % Exit if there's no valid selected robot
            end
        
        end

        % Value changed function: RobotSwitch
        function RobotSwitchValueChanged(app, event)
            value = app.RobotSwitch.Value;
        
            if strcmp(value, 'Harvester')
                app.selectedRobot = app.harvesterBot;
                q = app.selectedRobot.model.getpos();
               
                % Set limits for Panda robot
                app.Joint1Slider.Limits = [-pi, pi]*pi/180;
                app.Joint2Slider.Limits = [-2.26893, 2.07694];
                app.Joint3Slider.Limits = [-pi, pi];
                app.Joint4Slider.Limits = [-2.1293, 2.0944];
                app.Joint5Slider.Limits = [-pi, pi];
                app.Joint6Slider.Limits = [-pi, pi];
                app.Joint7Slider.Limits = [-pi, pi];
                
                % Initialize each slider to the current joint position
                app.Joint1Slider.Value = min(max(q(1, 1), app.Joint1Slider.Limits(1)), app.Joint1Slider.Limits(2));
                app.Joint2Slider.Value = min(max(q(1, 2), app.Joint2Slider.Limits(1)), app.Joint2Slider.Limits(2));
                app.Joint3Slider.Value = min(max(q(1, 3), app.Joint3Slider.Limits(1)), app.Joint3Slider.Limits(2));
                app.Joint4Slider.Value = min(max(q(1, 5), app.Joint4Slider.Limits(1)), app.Joint4Slider.Limits(2));
                app.Joint5Slider.Value = min(max(q(1, 7), app.Joint5Slider.Limits(1)), app.Joint5Slider.Limits(2));
                app.Joint6Slider.Value = min(max(q(1, 9), app.Joint6Slider.Limits(1)), app.Joint6Slider.Limits(2));
                app.Joint7Slider.Value = min(max(q(1, 11), app.Joint7Slider.Limits(1)), app.Joint7Slider.Limits(2));
        
            
            elseif strcmp(value, 'Sorter')
                app.selectedRobot = app.sortingBot;
                q = app.selectedRobot.model.getpos();
        
                % Set limits and initial values for the UR3e
                app.Joint1Slider.Limits = [-0.8, -0.01]; % Linear rail (prismatic joint)
                app.Joint2Slider.Limits = [-2*pi, 2*pi];
                app.Joint3Slider.Limits = [-pi/2, pi/2];
                app.Joint4Slider.Limits = [-170*pi/180, 170*pi/180];
                app.Joint5Slider.Limits = [-2*pi, 2*pi];
                app.Joint6Slider.Limits = [-2*pi, 2*pi];
                app.Joint7Slider.Limits = [-2*pi, 2*pi];
                
                % Initialize each slider to the current joint position
                app.Joint1Slider.Value = min(max(q(1, 1), app.Joint1Slider.Limits(1)), app.Joint1Slider.Limits(2));
                app.Joint2Slider.Value = min(max(q(1, 2), app.Joint2Slider.Limits(1)), app.Joint2Slider.Limits(2));
                app.Joint3Slider.Value = min(max(q(1, 3), app.Joint3Slider.Limits(1)), app.Joint3Slider.Limits(2));
                app.Joint4Slider.Value = min(max(q(1, 4), app.Joint4Slider.Limits(1)), app.Joint4Slider.Limits(2));
                app.Joint5Slider.Value = min(max(q(1, 5), app.Joint5Slider.Limits(1)), app.Joint5Slider.Limits(2));
                app.Joint6Slider.Value = min(max(q(1, 6), app.Joint6Slider.Limits(1)), app.Joint6Slider.Limits(2));
                app.Joint7Slider.Value = min(max(q(1, 7), app.Joint7Slider.Limits(1)), app.Joint7Slider.Limits(2));
            end
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 1, sliderValue);
        end

        % Value changed function: GripperSlider
        function GripperSliderValueChanged(app, event)
            gripperAnimation(app,event.Value)
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 2, sliderValue);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 3, sliderValue);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 4, sliderValue);
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 5, sliderValue);
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 6, sliderValue);
        end

        % Value changed function: Joint7Slider
        function Joint7SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 7, sliderValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ProduceButtonGroup = uibuttongroup(app.UIFigure);
            app.ProduceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProduceButtonGroupSelectionChanged, true);
            app.ProduceButtonGroup.Title = 'Produce';
            app.ProduceButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ProduceButtonGroupButtonDown, true);
            app.TomatoesButton = uitogglebutton(app.ProduceButtonGroup);
            app.TomatoesButton.Icon = fullfile(pathToMLAPP, 'Icons', 'tomato.png');
            app.TomatoesButton.Text = 'Tomatoes';
            app.PotatoesButton = uitogglebutton(app.ProduceButtonGroup);
            app.PotatoesButton.Icon = fullfile(pathToMLAPP, 'Icons', 'potato.png');
            app.PotatoesButton.Text = 'Potatoes';
            app.MixedButton = uitogglebutton(app.ProduceButtonGroup);
            app.MixedButton.Icon = fullfile(pathToMLAPP, 'Icons', 'vegetables.png');
            app.MixedButton.Text = 'Mixed';
            app.Button_2 = uitogglebutton(app.ProduceButtonGroup);
            app.Button_2.Text = '';
            app.Button_2.Value = true;
            app.RobotActionButtonGroup = uibuttongroup(app.UIFigure);
            app.RobotActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProduceButtonGroupButtonDown, true);
            app.RobotActionButtonGroup.Title = 'Robot Action';
            app.HarvestOnlyButton = uitogglebutton(app.RobotActionButtonGroup);
            app.HarvestOnlyButton.Icon = fullfile(pathToMLAPP, 'Icons', 'robotics.png');
            app.HarvestOnlyButton.Text = 'Harvest Only';
            app.DualActionButton = uitogglebutton(app.RobotActionButtonGroup);
            app.DualActionButton.Icon = fullfile(pathToMLAPP, 'Icons', 'settings-gears.png');
            app.DualActionButton.Text = 'Dual Action';
            app.Button = uitogglebutton(app.RobotActionButtonGroup);
            app.Button.Text = '';
            app.Button.Value = true;
            app.SortOnlyButton = uitogglebutton(app.RobotActionButtonGroup);
            app.SortOnlyButton.Icon = fullfile(pathToMLAPP, 'Icons', 'sorting.png');
            app.SortOnlyButton.Text = 'Sort Only';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'Icons', 'emergency-button.png');
            app.Button_3.Text = '';
            app.STARTButton_2 = uibutton(app.UIFigure, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'harvestmateLogo.png');
            app.GripperSliderLabel = uilabel(app.UIFigure);
            app.GripperSliderLabel.Text = 'Gripper';
            app.GripperSlider = uislider(app.UIFigure);
            app.GripperSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.GripperSlider.ValueChangedFcn = createCallbackFcn(app, @GripperSliderValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.ManualSwitch = uiswitch(app.UIFigure, 'slider');
            app.ManualSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualSwitchValueChanged, true);
            app.ManualModeControlsLabel = uilabel(app.UIFigure);
            app.ManualModeControlsLabel.Text = 'Manual Mode Controls';
            app.RobotSwitch = uiswitch(app.UIFigure, 'rocker');
            app.RobotSwitch.Items = {'Sorter', 'Harvester'};
            app.RobotSwitch.ValueChangedFcn = createCallbackFcn(app, @RobotSwitchValueChanged, true);
            app.RobotSwitch.Value = 'Harvester';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.Value = 50;
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.Value = 50;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.Value = 50;
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.Value = 50;
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.Value = 50;
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint6Slider.Value = 50;
            app.Joint7SliderLabel = uilabel(app.UIFigure);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.UIFigure);
            app.Joint7Slider.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderValueChanged, true);
            app.Joint7Slider.Value = 50;
            app.ManualModeLampLabel = uilabel(app.UIFigure);
            app.ManualModeLampLabel.Text = 'Manual Mode';
            app.ManualModeLamp = uilamp(app.UIFigure);
            app.ESTOPLabel = uilabel(app.UIFigure);
            app.ESTOPLabel.Text = 'E-STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarvestMate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end